classdef LabFinalTopicos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LaboratorioFinalTopicosPanel  matlab.ui.container.Panel
        EntradaButtonGroup            matlab.ui.container.ButtonGroup
        SenoButton                    matlab.ui.control.RadioButton
        RampaButton                   matlab.ui.control.RadioButton
        EscalonButton                 matlab.ui.control.RadioButton
        ControlButtonGroup            matlab.ui.container.ButtonGroup
        ServoButton                   matlab.ui.control.RadioButton
        LGRButton                     matlab.ui.control.RadioButton
        ReiniciarButton               matlab.ui.control.Button
        IniciarButton                 matlab.ui.control.Button
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2_4                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        
        control=0;
        entrada=0;% Description
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        set(app.ReiniciarButton,'Enable','off')

        end

        % Callback function
        function AnulacinplantaSwitchValueChanged(app, event)
            value = app.AnulacinplantaSwitch.Value;
            if strcmp(value,'On')
                app.control=2;
            else
                app.control=0;
            end
        end

        % Callback function
        function EscalnSwitchValueChanged(app, event)
            value = app.EscalnSwitch.Value;
            if strcmp(value,'On')
                app.entrada=1;
            else
                app.entrada=0;
            end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            clc
            set(app.IniciarButton,'Enable','off')
            delete(instrfind({'Port'},{'COM2'}));%borrar datos previos
            PS=serial('COM2')
            PS.BaudRate=9600;
            %abrir puerto
            fopen(PS)
            if app.control==1 && app.entrada==1
                fprintf(PS,'a')
            elseif app.control==1 && app.entrada==2
                fprintf(PS,'b')
            elseif app.control==1 && app.entrada==3
                fprintf(PS,'c')
            end
            
            if app.control==2 && app.entrada==1
                fprintf(PS,'d')
            elseif app.control==2 && app.entrada==2
                fprintf(PS,'e')
            elseif app.control==2 && app.entrada==3
                fprintf(PS,'f')
            end
            
            b=zeros(1,255);
            d=zeros(1,255);
            f=zeros(1,255);
            h=zeros(1,255);
            for i=1:255
            a=fscanf(PS)
            aaa=str2double(a)
            b(i)=aaa;
            end
            plot(app.UIAxes2_3,b,'r','linewidth',2)
            app.UIAxes2_3.XLim=[0 255]
            app.UIAxes2_3.YLim=[-1.5 1.5]
            
            for i=1:255
             c=fscanf(PS)
             ccc=str2double(c)
             d(i)=ccc;
            end
            plot(app.UIAxes2,d,'r','linewidth',2)
            app.UIAxes2.XLim=[0 255]
            
            for i=1:255
             e=fscanf(PS)
             eee=str2double(e)
             f(i)=eee;
            end
            plot(app.UIAxes2_2,f,'r','linewidth',2)
            app.UIAxes2_2.XLim=[0 255]
            app.UIAxes2_2.YLim=[-1 1]
            
            for i=1:255
             g=fscanf(PS)
             ggg=str2double(g)
             h(i)=ggg;
            end
            plot(app.UIAxes2_4,h,'r','linewidth',2)
            app.UIAxes2_4.XLim=[0 255]


           %x=linspace(3,6); plot(x,sin(x), 'linewidth',12)
           

           %%set(app.Label,'Text',a)
            %cerrar puerto
            fclose(PS)
            set(app.ReiniciarButton,'Enable','on')

        end

        % Callback function
        function RampaSwitchValueChanged(app, event)
            value = app.RampaSwitch.Value;
            if strcmp(value,'On')
                app.entrada=2;
            else
                app.entrada=0;
            end
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            clc
            set(app.ReiniciarButton,'Enable','off')
            delete(instrfind({'Port'},{'COM2'}));%borrar datos previos
            PS=serial('COM2')
            PS.BaudRate=9600;
            %abrir puerto
            fopen(PS)
            fprintf(PS,'g')
            fclose(PS)  
           
            for i=1:255
            b(i)=0;
            d(i)=0
            f(i)=0;
            h(i)=0;
            
            end
            
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            cla(app.UIAxes2_4);
            
            set(app.IniciarButton,'Enable','on')


           %x=linspace(3,6); plot(x,sin(x), 'linewidth',12)
           

            %cerrar puerto
              
        end

        % Callback function
        function SenoSwitchValueChanged(app, event)
            value = app.SenoSwitch.Value;
            if strcmp(value,'On')
             
                app.entrada=3;
            else
               
                app.entrada=0;
            end
        end

        % Callback function
        function ServosistemaSwitchValueChanged(app, event)
            value = app.ServosistemaSwitch.Value;
            if strcmp(value,'On')
              
                app.control=1;
            else
               
                app.control=0;
            end
        end

        % Selection changed function: EntradaButtonGroup
        function EntradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.EntradaButtonGroup.SelectedObject;
              
            in1=app.EscalonButton.Value;
            in2=app.RampaButton.Value;
            in3=app.SenoButton.Value;
            
            if in1 == 1
                app.entrada=1;
            elseif in2 == 1
                app.entrada=2;
            elseif in3 == 1
                app.entrada=3;
            else
                app.entrada=0;
            end
        end

        % Selection changed function: ControlButtonGroup
        function ControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlButtonGroup.SelectedObject;
            con1=app.LGRButton.Value;
            con2=app.ServoButton.Value;
            
            if con1 == 1
                app.control=2;
            elseif con2 == 1
                app.control=1;
            else
                app.control=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LaboratorioFinalTopicosPanel = uipanel(app.UIFigure);
            app.LaboratorioFinalTopicosPanel.Title = 'Laboratorio Final Topicos';
            app.UIAxes2_4 = uiaxes(app.LaboratorioFinalTopicosPanel);
            title(app.UIAxes2_4, 'Error')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            app.UIAxes2_2 = uiaxes(app.LaboratorioFinalTopicosPanel);
            title(app.UIAxes2_2, 'Theta 2')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2 = uiaxes(app.LaboratorioFinalTopicosPanel);
            title(app.UIAxes2, 'Theta 1')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_3 = uiaxes(app.LaboratorioFinalTopicosPanel);
            title(app.UIAxes2_3, 'Entrada')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            app.IniciarButton = uibutton(app.LaboratorioFinalTopicosPanel, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.ReiniciarButton = uibutton(app.LaboratorioFinalTopicosPanel, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.ControlButtonGroup = uibuttongroup(app.LaboratorioFinalTopicosPanel);
            app.ControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlButtonGroupSelectionChanged, true);
            app.ControlButtonGroup.Title = 'Control';
            app.LGRButton = uiradiobutton(app.ControlButtonGroup);
            app.LGRButton.Text = 'LGR';
            app.LGRButton.Value = true;
            app.ServoButton = uiradiobutton(app.ControlButtonGroup);
            app.ServoButton.Text = 'Servo';
            app.EntradaButtonGroup = uibuttongroup(app.LaboratorioFinalTopicosPanel);
            app.EntradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EntradaButtonGroupSelectionChanged, true);
            app.EntradaButtonGroup.Title = 'Entrada';
            app.EscalonButton = uiradiobutton(app.EntradaButtonGroup);
            app.EscalonButton.Text = 'Escalon';
            app.EscalonButton.Value = true;
            app.RampaButton = uiradiobutton(app.EntradaButtonGroup);
            app.RampaButton.Text = 'Rampa';
            app.SenoButton = uiradiobutton(app.EntradaButtonGroup);
            app.SenoButton.Text = 'Seno';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabFinalTopicos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end